classdef main_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Histogram                    matlab.ui.container.Tab
        DropDownHistogram            matlab.ui.control.DropDown
        Label_4                      matlab.ui.control.Label
        ImgHistogramGray             matlab.ui.control.Image
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        ImageHistogramInput          matlab.ui.control.Image
        BtnHistogramEqualization     matlab.ui.control.Button
        ImgHistogramEqualization     matlab.ui.control.Image
        UIAxesGrayHistogramHistgram  matlab.ui.control.UIAxes
        Enhance                      matlab.ui.container.Tab
        ImgEnhanceGray               matlab.ui.control.Image
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        ImgEnhanceInput              matlab.ui.control.Image
    end


    methods (Access = public)

        % 统计图像灰度直方图
        function histogram = gray_histogram(app, image)

            histogram = zeros(256, 1);
            [height, width] = size(image);

            for x = 1:width
                for y = 1:height
                    pixelValue = image(y, x) + 1;
                    histogram(pixelValue) = histogram(pixelValue) + 1;
                end
            end
        end


        % 绘制灰度直方图数组到坐标轴
        function draw_histogram(app, histogram)
            % 获取灰度直方图坐标轴组件
            axesHandle = app.UIAxesGrayHistogramHistgram;

            % 绘制直方图
            bar(axesHandle, histogram);

        end


        % 直方图均衡化
        function equalized_image  = histogram_equalization(~, image)

            histgram = imhist(image);
            [h, w] = size(image);
            equalized_image = zeros(h, w, 'uint8'); % Initialize as uint8
            s = zeros(256); s(1) = histgram(1);

            for t = 2 : 256
                s(t) = s(t - 1) + histgram(t);
            end

            for x = 1: w
                for y = 1: h
                    equalized_image(y, x) = uint8(255 * s(image(y, x) + 1) / (w * h));

                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: ImageHistogramInput
        function ImgeHistogramInputImageOnClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);
            app.ImageHistogramInput.ImageSource = imgData;
            gray_image = rgb2gray(imgData);
            app.ImgHistogramGray.ImageSource =  cat(3, gray_image, gray_image, gray_image);
            histogram = gray_histogram(app, rgb2gray(app.ImageHistogramInput.ImageSource));
            draw_histogram(app, histogram);
        end

        % Image clicked function: ImgEnhanceInput
        function ImgEnhanceInputClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);
            app.ImgEnhanceInput.ImageSource = imgData;
            gray_image = rgb2gray(imgData);
            app.ImgEnhanceGray.ImageSource =  cat(3, gray_image, gray_image, gray_image);

        end

        % Button pushed function: BtnHistogramEqualization
        function BtnHistogramEqualizationButtonPushed(app, event)
            if ~isempty(app.ImageHistogramInput.ImageSource)

                equalized_image = histogram_equalization(app, rgb2gray(app.ImageHistogramInput.ImageSource));
                app.ImgHistogramEqualization.ImageSource = cat(3, equalized_image, equalized_image, equalized_image);
            else

                disp('输入图像为空');
            end
        end

        % Value changed function: DropDownHistogram
        function DropDownHistogramValueChanged(app, event)
            value = app.DropDownHistogram.Value;

            if strcmp(value, '原图')
                histogram = gray_histogram(app, rgb2gray(app.ImageHistogramInput.ImageSource));
                draw_histogram(app, histogram);
            elseif strcmp(value, '均衡化')
                histogram = gray_histogram(app, app.ImgHistogramEqualization.ImageSource);
                draw_histogram(app, histogram);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Histogram = uitab(app.TabGroup);
            app.Histogram.Title = '直方图处理';
            app.UIAxesGrayHistogramHistgram = uiaxes(app.Histogram);
            title(app.UIAxesGrayHistogramHistgram, '灰度直方图')
            xlabel(app.UIAxesGrayHistogramHistgram, '灰度值')
            ylabel(app.UIAxesGrayHistogramHistgram, '频次')
            zlabel(app.UIAxesGrayHistogramHistgram, 'Z')
            app.ImgHistogramEqualization = uiimage(app.Histogram);
            app.BtnHistogramEqualization = uibutton(app.Histogram, 'push');
            app.BtnHistogramEqualization.ButtonPushedFcn = createCallbackFcn(app, @BtnHistogramEqualizationButtonPushed, true);
            app.BtnHistogramEqualization.Text = '直方图均衡化';
            app.ImageHistogramInput = uiimage(app.Histogram);
            app.ImageHistogramInput.ImageClickedFcn = createCallbackFcn(app, @ImgeHistogramInputImageOnClicked, true);
            app.Label = uilabel(app.Histogram);
            app.Label.Text = '原始图像';
            app.Label_2 = uilabel(app.Histogram);
            app.Label_2.Text = '直方图均衡化';
            app.Label_3 = uilabel(app.Histogram);
            app.Label_3.Text = '灰度图';
            app.ImgHistogramGray = uiimage(app.Histogram);
            app.Label_4 = uilabel(app.Histogram);
            app.Label_4.Text = '选择要显示灰度直方图的图像';
            app.DropDownHistogram = uidropdown(app.Histogram);
            app.DropDownHistogram.Items = {'原图', '均衡化'};
            app.DropDownHistogram.ValueChangedFcn = createCallbackFcn(app, @DropDownHistogramValueChanged, true);
            app.DropDownHistogram.Value = '原图';
            app.Enhance = uitab(app.TabGroup);
            app.Enhance.Title = '灰度图像的对比度增强';
            app.ImgEnhanceInput = uiimage(app.Enhance);
            app.ImgEnhanceInput.ImageClickedFcn = createCallbackFcn(app, @ImgEnhanceInputClicked, true);
            app.Label_5 = uilabel(app.Enhance);
            app.Label_5.Text = '原始图像';
            app.Label_6 = uilabel(app.Enhance);
            app.Label_6.Text = '灰度图';
            app.ImgEnhanceGray = uiimage(app.Enhance);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_view

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end